classdef RC2_DoubleRotation_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        messageButton               matlab.ui.control.StateButton
        messageLable                matlab.ui.control.Label
        versionLable_2              matlab.ui.control.Label
        versionLable                matlab.ui.control.Label
        AIPreviewPanel              matlab.ui.container.Panel
        PreviewButton               matlab.ui.control.StateButton
        SoundPanel                  matlab.ui.container.Panel
        PlaySoundButton             matlab.ui.control.StateButton
        SoundButtonGroup            matlab.ui.container.ButtonGroup
        DisableSoundButton          matlab.ui.control.RadioButton
        EnableSoundButton           matlab.ui.control.RadioButton
        PumpPanel                   matlab.ui.container.Panel
        DurationEditField           matlab.ui.control.EditField
        DurationEditField_2Label    matlab.ui.control.Label
        PumpONButton                matlab.ui.control.StateButton
        DurationUnitmsLabel         matlab.ui.control.Label
        RewardButton                matlab.ui.control.Button
        SavingPanel                 matlab.ui.container.Panel
        DataFilename_val            matlab.ui.control.Label
        DataFilenameLabel           matlab.ui.control.Label
        SavePathEditField           matlab.ui.control.EditField
        PathEditFieldLabel          matlab.ui.control.Label
        SetsavetoButton             matlab.ui.control.Button
        SessionEditField            matlab.ui.control.EditField
        SessionLabel                matlab.ui.control.Label
        AnimalIDEditField           matlab.ui.control.EditField
        AnimalIDLabel               matlab.ui.control.Label
        ExperimentPanel             matlab.ui.container.Panel
        StartExperimentButton       matlab.ui.control.StateButton
        LoadProtocolButton          matlab.ui.control.Button
        LoadProtocolEditField       matlab.ui.control.EditField
        LoadProtocolEditFieldLabel  matlab.ui.control.Label
        StagePanel                  matlab.ui.container.Panel
        OuterPanel                  matlab.ui.container.Panel
        SpeedEditField_2            matlab.ui.control.NumericEditField
        SpeedEditField_2Label       matlab.ui.control.Label
        PositionEditField_2         matlab.ui.control.NumericEditField
        PositionEditField_2Label    matlab.ui.control.Label
        MoveToButton_2              matlab.ui.control.Button
        degsLabel_2                 matlab.ui.control.Label
        degLabel_2                  matlab.ui.control.Label
        StopButton_2                matlab.ui.control.Button
        ResetButton_2               matlab.ui.control.Button
        HOMEButton_2                matlab.ui.control.Button
        CentralPanel                matlab.ui.container.Panel
        SpeedEditField              matlab.ui.control.NumericEditField
        SpeedEditFieldLabel         matlab.ui.control.Label
        PositionEditField           matlab.ui.control.NumericEditField
        PositionEditFieldLabel      matlab.ui.control.Label
        MoveToButton                matlab.ui.control.Button
        degsLabel                   matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        HOMEButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ctl % gui controller
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ctl)
            app.ctl = ctl;
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            % Central Stage Panel Home Button
            app.ctl.set_target_axes( [app.ctl.setup.ensemble.all_axes(1) NaN] );
            app.ctl.home_ensemble(false);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Central Stage Panel Reset Button
            app.ctl.set_target_axes( [app.ctl.setup.ensemble.all_axes(1) NaN] );
            app.ctl.reset_ensemble();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Central Stage Panel Stop Button
            app.ctl.set_target_axes( [app.ctl.setup.ensemble.all_axes(1) NaN] );
            app.ctl.stop_ensemble();
        end

        % Value changed function: PositionEditField
        function PositionEditFieldValueChanged(app, event)
            % Central Stage Panel Position Edit Field
            value = app.PositionEditField.Value;
            app.ctl.set_target_axes( [app.ctl.setup.ensemble.all_axes(1) NaN] );
            app.ctl.changed_move_to_pos();
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            % Central Stage Panel Speed Edit Field
            value = app.SpeedEditField.Value;
            app.ctl.set_target_axes( [app.ctl.setup.ensemble.all_axes(1) NaN] );
            app.ctl.changed_speed();
        end

        % Button pushed function: MoveToButton
        function MoveToButtonPushed(app, event)
            % Central Stage Panel Move To Button
            app.ctl.set_target_axes( [app.ctl.setup.ensemble.all_axes(1) NaN] );
            app.ctl.move_to(true);
        end

        % Button pushed function: HOMEButton_2
        function HOMEButton_2Pushed(app, event)
            % Outer Stage Panel Home Button
            app.ctl.set_target_axes( [NaN app.ctl.setup.ensemble.all_axes(2)] );
            app.ctl.home_ensemble(false);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            % Outer Stage Panel Reset Button
            app.ctl.set_target_axes( [NaN app.ctl.setup.ensemble.all_axes(2)] );
            app.ctl.reset_ensemble();
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            % Outer Stage Panel Stop Button
            app.ctl.set_target_axes( [NaN app.ctl.setup.ensemble.all_axes(2)] );
            app.ctl.stop_ensemble();
        end

        % Value changed function: PositionEditField_2
        function PositionEditField_2ValueChanged(app, event)
            % Outer Stage Panel Position Edit Field
            value = app.PositionEditField_2.Value;
            app.ctl.set_target_axes( [NaN app.ctl.setup.ensemble.all_axes(2)] );
            app.ctl.changed_move_to_pos_2();
        end

        % Value changed function: SpeedEditField_2
        function SpeedEditField_2ValueChanged(app, event)
            % Outer Stage Panel Speed Edit Field
            value = app.SpeedEditField_2.Value;
            app.ctl.set_target_axes( [NaN app.ctl.setup.ensemble.all_axes(2)] );
            app.ctl.changed_speed_2();
        end

        % Button pushed function: MoveToButton_2
        function MoveToButton_2Pushed(app, event)
            % Outer Stage Panel Move To Button
            app.ctl.set_target_axes( [NaN app.ctl.setup.ensemble.all_axes(2)] );
            app.ctl.move_to(true);
        end

        % Button pushed function: LoadProtocolButton
        function LoadProtocolButtonPushed(app, event)
            % Experiment Panel ... Button
            app.ctl.set_script();
        end

        % Value changed function: StartExperimentButton
        function StartExperimentButtonValueChanged(app, event)
            % Experiment Panel Start Experiment Button
%             value = app.StartExperimentButton.Value;
            app.ctl.start_experiment();
        end

        % Button pushed function: SetsavetoButton
        function SetsavetoButtonPushed(app, event)
            % Save Panel ... Button
            app.ctl.set_save_to();
        end

        % Value changed function: SavePathEditField
        function SavePathEditFieldValueChanged(app, event)
            % Save Panel SavePath Edit Field
%             value = app.SavePathEditField.Value;
            app.ctl.set_file_path();
        end

        % Value changed function: AnimalIDEditField
        function AnimalIDEditFieldValueChanged(app, event)
            % Save Panel AnimalID Edit Field
%             value = app.AnimalIDEditField.Value;
            app.ctl.set_file_prefix();
        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            % Save Panel Session Edit Field
%             value = app.SessionEditField.Value;
            app.ctl.set_file_suffix();
        end

        % Value changed function: PumpONButton
        function PumpONButtonValueChanged(app, event)
            % Pump Panel ON Button
            app.ctl.pump_on();
        end

        % Button pushed function: RewardButton
        function RewardButtonPushed(app, event)
            % Pump Panel Reward Button
            app.ctl.give_reward();
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            % Pump Panel duration Edit Field
            app.ctl.changed_reward_duration();
        end

        % Value changed function: PlaySoundButton
        function PlaySoundButtonValueChanged(app, event)
            % Sound Panel Play Button
%             value = app.PlaySoundButton.Value;
            app.ctl.toggle_sound();
        end

        % Selection changed function: SoundButtonGroup
        function SoundButtonGroupSelectionChanged(app, event)
            % Sound Panel enable sound
            selectedButton = app.SoundButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Enable')
                app.ctl.enable_sound();
            else
                app.ctl.disable_sound();
            end
        end

        % Value changed function: PreviewButton
        function PreviewButtonValueChanged(app, event)
            % AI Preview Panel Preview Button
%             value = app.PreviewButton.Value;
            app.ctl.toggle_acquisition();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % minimize the window when close
            app.UIFigure.WindowState = 'minimized';
        end

        % Value changed function: messageButton
        function messageButtonValueChanged(app, event)
%             value = app.messageButton.Value;
            app.ctl.acknowledge_error();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StagePanel = uipanel(app.UIFigure);
            app.StagePanel.Title = 'Stage';
            app.CentralPanel = uipanel(app.StagePanel);
            app.CentralPanel.Title = 'Central';
            app.HOMEButton = uibutton(app.CentralPanel, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.ResetButton = uibutton(app.CentralPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.StopButton = uibutton(app.CentralPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.degLabel = uilabel(app.CentralPanel);
            app.degLabel.Text = 'deg';
            app.degsLabel = uilabel(app.CentralPanel);
            app.degsLabel.Text = 'deg/s';
            app.MoveToButton = uibutton(app.CentralPanel, 'push');
            app.MoveToButton.ButtonPushedFcn = createCallbackFcn(app, @MoveToButtonPushed, true);
            app.MoveToButton.Text = 'Move To';
            app.PositionEditFieldLabel = uilabel(app.CentralPanel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.CentralPanel, 'numeric');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.SpeedEditFieldLabel = uilabel(app.CentralPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.CentralPanel, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.OuterPanel = uipanel(app.StagePanel);
            app.OuterPanel.Title = 'Outer';
            app.HOMEButton_2 = uibutton(app.OuterPanel, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_2Pushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.ResetButton_2 = uibutton(app.OuterPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.StopButton_2 = uibutton(app.OuterPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.degLabel_2 = uilabel(app.OuterPanel);
            app.degLabel_2.Text = 'deg';
            app.degsLabel_2 = uilabel(app.OuterPanel);
            app.degsLabel_2.Text = 'deg/s';
            app.MoveToButton_2 = uibutton(app.OuterPanel, 'push');
            app.MoveToButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_2Pushed, true);
            app.MoveToButton_2.Text = 'Move To';
            app.PositionEditField_2Label = uilabel(app.OuterPanel);
            app.PositionEditField_2Label.Text = 'Position';
            app.PositionEditField_2 = uieditfield(app.OuterPanel, 'numeric');
            app.PositionEditField_2.ValueChangedFcn = createCallbackFcn(app, @PositionEditField_2ValueChanged, true);
            app.SpeedEditField_2Label = uilabel(app.OuterPanel);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.SpeedEditField_2 = uieditfield(app.OuterPanel, 'numeric');
            app.SpeedEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpeedEditField_2ValueChanged, true);
            app.ExperimentPanel = uipanel(app.UIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.LoadProtocolEditFieldLabel = uilabel(app.ExperimentPanel);
            app.LoadProtocolEditFieldLabel.Text = 'Load Protocol';
            app.LoadProtocolEditField = uieditfield(app.ExperimentPanel, 'text');
            app.LoadProtocolEditField.Editable = 'off';
            app.LoadProtocolEditField.Value = 'Protocol1';
            app.LoadProtocolButton = uibutton(app.ExperimentPanel, 'push');
            app.LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProtocolButtonPushed, true);
            app.LoadProtocolButton.Text = '...';
            app.StartExperimentButton = uibutton(app.ExperimentPanel, 'state');
            app.StartExperimentButton.ValueChangedFcn = createCallbackFcn(app, @StartExperimentButtonValueChanged, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.SavingPanel = uipanel(app.UIFigure);
            app.SavingPanel.Title = 'Saving';
            app.AnimalIDLabel = uilabel(app.SavingPanel);
            app.AnimalIDLabel.Text = 'Animal ID #';
            app.AnimalIDEditField = uieditfield(app.SavingPanel, 'text');
            app.AnimalIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalIDEditFieldValueChanged, true);
            app.AnimalIDEditField.Value = 'M01';
            app.SessionLabel = uilabel(app.SavingPanel);
            app.SessionLabel.Text = 'Session #';
            app.SessionEditField = uieditfield(app.SavingPanel, 'text');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.SessionEditField.Value = 'S01';
            app.SetsavetoButton = uibutton(app.SavingPanel, 'push');
            app.SetsavetoButton.ButtonPushedFcn = createCallbackFcn(app, @SetsavetoButtonPushed, true);
            app.SetsavetoButton.Text = '...';
            app.PathEditFieldLabel = uilabel(app.SavingPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.SavePathEditField = uieditfield(app.SavingPanel, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @SavePathEditFieldValueChanged, true);
            app.SavePathEditField.Editable = 'off';
            app.SavePathEditField.Value = 'C:\Users\';
            app.DataFilenameLabel = uilabel(app.SavingPanel);
            app.DataFilenameLabel.Text = 'DataFilename:';
            app.DataFilename_val = uilabel(app.SavingPanel);
            app.DataFilename_val.Text = 'null';
            app.PumpPanel = uipanel(app.UIFigure);
            app.PumpPanel.Title = 'Pump';
            app.RewardButton = uibutton(app.PumpPanel, 'push');
            app.RewardButton.ButtonPushedFcn = createCallbackFcn(app, @RewardButtonPushed, true);
            app.RewardButton.Text = 'Reward';
            app.DurationUnitmsLabel = uilabel(app.PumpPanel);
            app.DurationUnitmsLabel.Text = 'ms';
            app.PumpONButton = uibutton(app.PumpPanel, 'state');
            app.PumpONButton.ValueChangedFcn = createCallbackFcn(app, @PumpONButtonValueChanged, true);
            app.PumpONButton.Text = 'ON';
            app.DurationEditField_2Label = uilabel(app.PumpPanel);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField = uieditfield(app.PumpPanel, 'text');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.DurationEditField.Value = '10';
            app.SoundPanel = uipanel(app.UIFigure);
            app.SoundPanel.Title = 'Sound';
            app.SoundButtonGroup = uibuttongroup(app.SoundPanel);
            app.SoundButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoundButtonGroupSelectionChanged, true);
            app.EnableSoundButton = uiradiobutton(app.SoundButtonGroup);
            app.EnableSoundButton.Text = 'Enable';
            app.EnableSoundButton.Value = true;
            app.DisableSoundButton = uiradiobutton(app.SoundButtonGroup);
            app.DisableSoundButton.Text = 'Disable';
            app.PlaySoundButton = uibutton(app.SoundPanel, 'state');
            app.PlaySoundButton.ValueChangedFcn = createCallbackFcn(app, @PlaySoundButtonValueChanged, true);
            app.PlaySoundButton.Text = 'PLAY';
            app.AIPreviewPanel = uipanel(app.UIFigure);
            app.AIPreviewPanel.Title = 'AI Preview';
            app.PreviewButton = uibutton(app.AIPreviewPanel, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.PreviewButton.Text = 'Preview';
            app.versionLable = uilabel(app.UIFigure);
            app.versionLable.Text = 'Version';
            app.versionLable_2 = uilabel(app.UIFigure);
            app.versionLable_2.Text = '0.0';
            app.messageLable = uilabel(app.UIFigure);
            app.messageLable.Text = '';
            app.messageButton = uibutton(app.UIFigure, 'state');
            app.messageButton.ValueChangedFcn = createCallbackFcn(app, @messageButtonValueChanged, true);
            app.messageButton.Visible = 'off';
            app.messageButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RC2_DoubleRotation_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end