classdef GUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LaserPanel                 matlab.ui.container.Panel
        recoversEditField          matlab.ui.control.EditField
        recoversEditFieldLabel     matlab.ui.control.Label
        laserstateLamp             matlab.ui.control.Lamp
        stateLamp_2Label           matlab.ui.control.Label
        SinglePulseButton          matlab.ui.control.Button
        pulsewidthsEditField       matlab.ui.control.EditField
        pulsewidthsEditFieldLabel  matlab.ui.control.Label
        RepeatedPulseButton        matlab.ui.control.StateButton
        LaserONButton              matlab.ui.control.StateButton
        ExperimentPanel            matlab.ui.container.Panel
        stateLamp                  matlab.ui.control.Lamp
        stateLampLabel             matlab.ui.control.Label
        StartExperimentButton      matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        ctrl    % Controller
    end
    
    properties (SetObservable = true)
        RepeatedPulseButtonValue % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ctrl)
            app.ctrl = ctrl;
            app.stateLamp.Color = [0.9,0.9,0.9];
        end

        % Value changed function: StartExperimentButton
        function StartExperimentButtonValueChanged(app, event)
            value = app.StartExperimentButton.Value;
            if value
                app.ctrl.start_experiment();
                app.stateLamp.Color = [0,1,0];
                app.StartExperimentButton.Text = 'Stop Experiment';
            else
                app.ctrl.stop_experiment();
                app.stateLamp.Color = [0.9,0.9,0.9];
                app.StartExperimentButton.Text = 'Start Experiment';
            end
        end

        % Value changed function: pulsewidthsEditField
        function pulsewidthsEditFieldValueChanged(app, event)
            value = app.pulsewidthsEditField.Value;
            app.ctrl.laser.pulse_width = str2double(value);
        end

        % Callback function: not associated with a component
        function durationsEditFieldValueChanged(app, event)
            value = app.durationsEditField.Value;
            app.ctrl.laser.pulse_duration = str2double(value);
        end

        % Value changed function: LaserONButton
        function LaserONButtonValueChanged(app, event)
            value = app.LaserONButton.Value;
            if value
                app.ctrl.laser.on();
                app.laserstateLamp.Color = [0,1,0];
                app.LaserONButton.Text = 'Laser OFF';
            else
                app.ctrl.laser.off();
                app.laserstateLamp.Color = [0.9,0.9,0.9];
                app.LaserONButton.Text = 'Laser ON';
            end
        end

        % Value changed function: RepeatedPulseButton
        function RepeatedPulseButtonValueChanged(app, event)
            app.RepeatedPulseButtonValue = app.RepeatedPulseButton.Value;
            if app.RepeatedPulseButtonValue
                app.ctrl.laser.pulse_repeat();
%                 app.laserstateLamp.Color = [0,1,0];
%                 app.RepeatedPulseButton.Text = 'Abort';
            else
                app.ctrl.laser.pulse_repeat_abort();
%                 app.laserstateLamp.Color = [0.9,0.9,0.9];
%                 app.RepeatedPulseButton.Text = 'Repeated Pulse';
            end
        end

        % Button pushed function: SinglePulseButton
        function SinglePulseButtonPushed(app, event)
            app.ctrl.laser.pulse();
        end

        % Value changed function: recoversEditField
        function recoversEditFieldValueChanged(app, event)
            value = app.recoversEditField.Value;
            app.ctrl.laser.pulse_recover = str2double(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExperimentPanel = uipanel(app.UIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.StartExperimentButton = uibutton(app.ExperimentPanel, 'state');
            app.StartExperimentButton.ValueChangedFcn = createCallbackFcn(app, @StartExperimentButtonValueChanged, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.stateLampLabel = uilabel(app.ExperimentPanel);
            app.stateLampLabel.Text = 'state';
            app.stateLamp = uilamp(app.ExperimentPanel);
            app.LaserPanel = uipanel(app.UIFigure);
            app.LaserPanel.Title = 'Laser';
            app.LaserONButton = uibutton(app.LaserPanel, 'state');
            app.LaserONButton.ValueChangedFcn = createCallbackFcn(app, @LaserONButtonValueChanged, true);
            app.LaserONButton.Text = 'Laser ON';
            app.RepeatedPulseButton = uibutton(app.LaserPanel, 'state');
            app.RepeatedPulseButton.ValueChangedFcn = createCallbackFcn(app, @RepeatedPulseButtonValueChanged, true);
            app.RepeatedPulseButton.Text = 'Repeated Pulse';
            app.pulsewidthsEditFieldLabel = uilabel(app.LaserPanel);
            app.pulsewidthsEditFieldLabel.Text = 'pulse width (s)';
            app.pulsewidthsEditField = uieditfield(app.LaserPanel, 'text');
            app.pulsewidthsEditField.ValueChangedFcn = createCallbackFcn(app, @pulsewidthsEditFieldValueChanged, true);
            app.SinglePulseButton = uibutton(app.LaserPanel, 'push');
            app.SinglePulseButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePulseButtonPushed, true);
            app.SinglePulseButton.Text = 'Single Pulse';
            app.stateLamp_2Label = uilabel(app.LaserPanel);
            app.stateLamp_2Label.Text = 'state';
            app.laserstateLamp = uilamp(app.LaserPanel);
            app.recoversEditFieldLabel = uilabel(app.LaserPanel);
            app.recoversEditFieldLabel.Text = 'recover (s)';
            app.recoversEditField = uieditfield(app.LaserPanel, 'text');
            app.recoversEditField.ValueChangedFcn = createCallbackFcn(app, @recoversEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end